classdef ReproductorDeMusica < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        ReproductordemsicaTab           matlab.ui.container.Tab
        CargarcarpetaButton             matlab.ui.control.Button
        CargararchivosButton            matlab.ui.control.Button
        DetenerButton                   matlab.ui.control.Button
        Image                           matlab.ui.control.Image
        ControlesdeaudioButtonGroup     matlab.ui.container.ButtonGroup
        PlayButton                      matlab.ui.control.Button
        SiguienteButton                 matlab.ui.control.Button
        PausaButton                     matlab.ui.control.Button
        AnteriorButton                  matlab.ui.control.Button
        CancinactualEditFieldLabel      matlab.ui.control.Label
        Cancinactual                    matlab.ui.control.EditField
        InformacindelprocesoLabel_2     matlab.ui.control.Label
        mensajesclavemusica             matlab.ui.control.TextArea
        pruebasButton                   matlab.ui.control.Button
        ProgresoLabel                   matlab.ui.control.Label
        Slidermusica                    matlab.ui.control.Slider
        KnobLabel                       matlab.ui.control.Label
        ruedamusica                     matlab.ui.control.DiscreteKnob
        ExtraccindeaudioTab             matlab.ui.container.Tab
        Image_2                         matlab.ui.control.Image
        CargarvideoButton               matlab.ui.control.Button
        ExtraeraudioButton              matlab.ui.control.Button
        ElegirformatodesalidadeaudioPanel  matlab.ui.container.Panel
        FormatosdisponiblesButtonGroup  matlab.ui.container.ButtonGroup
        wavButton                       matlab.ui.control.RadioButton
        oggButton                       matlab.ui.control.RadioButton
        flacButton                      matlab.ui.control.RadioButton
        mp4Button                       matlab.ui.control.RadioButton
        m4aButton                       matlab.ui.control.RadioButton
        PlayaudioButton                 matlab.ui.control.Button
        NombredelacancionButtonGroup    matlab.ui.container.ButtonGroup
        DefectoButton                   matlab.ui.control.RadioButton
        ActivarNombre                   matlab.ui.control.RadioButton
        NombreArchivoTexto              matlab.ui.control.EditField
        InformacindelprocesoLabel       matlab.ui.control.Label
        mensajesclave                   matlab.ui.control.TextArea
        Instrucciones1Cargararrchivodevideo2ElegirformatodelaudioLabel  matlab.ui.control.Label
        EditFieldLabel                  matlab.ui.control.Label
        time                            matlab.ui.control.EditField
        ButtonGroup                     matlab.ui.container.ButtonGroup
        SegundosButton                  matlab.ui.control.RadioButton
        MinutosButton                   matlab.ui.control.RadioButton
        Button3                         matlab.ui.control.RadioButton
    end

    
    methods (Access = private)
        
        function i = func(app,duracion)
            i = 1;
                while i<=duracion

                    %Iniciamos el contador
                    tic;
                    if app.PausaButton(app,events)
%                         estadopause = 0;
                        disp('pausa clicleada')
                        app.PausaButton(app,event)                        
                     end  
                    while(1)
                        temp=toc;
                        %En cada segundo imprimimos el número


                        if temp>1
                           clc;
                           disp(i)
                           datetime('now')
                         
                           break;
                        end
                    end
                    i=i+1;
                end
                 disp('Funcion extra terminada')
                 SiguienteButtonPushed(app)
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CargarcarpetaButton
        function CargarcarpetaButtonPushed(app, event)
            global canciones c player playestado dircanciones d y Fs

            try
                app.mensajesclavemusica.Value = ">> Cargando carpeta <<";
                d = uigetdir('C:\','Selecciar de folder de Audio');                                   
                app.UIFigure.Visible = 'off';              
                app.UIFigure.Visible = 'on'; 
                direc = dir(d);              
                T = struct2table(direc);             
                canciones = string(T.name);   
                point = char(46);
                disp(canciones)
                disp(d)
                if canciones(1) == char(46)                
                    canciones(1) = [];
                end             
                if canciones(1) == [point,point]               
                    canciones(1) = [];           
                end
          
                c=1;         
                playestado = 1;                        
                dircanciones = d+"\"+canciones;
                
                app.mensajesclavemusica.Value = ">> Carpeta " + ...
                    "lista >> " + d;
                
                [y,Fs] = audioread(dircanciones(c));            
                player = audioplayer(y,Fs);
                
                PlayButtonPushed(app, event)
            catch 
                playestado = []; 
%                 msgbox("Error" + ...
%                      " al cargar la carpeta," + ...
%                      " solo debe contener archivos de " + ...
%                      "audio.",'Alerta')
%                 app.mensajesclavemusica.Value = '';
%                 app.UIFigure.Visible = 'off';              
%                 app.UIFigure.Visible = 'on'; 
                 app.mensajesclavemusica.Value = ">> Error" + ...
                     "al cargar la carpeta," + ...
                     " solo debe contener archivos de audio.";
             end 

        end

        % Button pushed function: CargararchivosButton
        function CargararchivosButtonPushed(app, event)
            global dircanciones canciones d c playestado
        
            try 
                app.mensajesclavemusica.Value = ">> Cargando archivos <<";
                [canciones, d] = uigetfile({['*.wav;*.mp3;*.mp4;*.avi;' ...
                    '*.wmv;*.m4v;*.ogg;*.flac;' ...
                    '*.au;*.aiff;*.aif;*.aifc;' ...
                    '*.m4a;*.mp4;']},'Selección de Audio', ...
                     'MultiSelect', 'on'); 
                app.UIFigure.Visible = 'off';
                app.UIFigure.Visible = 'on';

                canciones = string(canciones);
                dircanciones = d+"\"+canciones;
                playestado = 1;
                c = 1;
                

                 nan = audioread(dircanciones(c));            
%                  player = audioplayer(y,Fs);

                PlayButtonPushed(app, event)
                                
            catch
                playestado = []; 
                app.mensajesclavemusica.Value = ["Error al cargar" + ...
                    "los archivos, solo deben ser de audio."
                     "" 
                    "Formatos disponibles: .wav .mp3 .mp4 " + ...
                    ".avi .wmv .m4v .ogg " + ...
                     ".flac .au .aiff, .aif .aifc .m4a, .mp4"];
            end
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            global canciones c player playestado y Fs dircanciones
            global duracion i estadopause estadopause2
            
            try 
                if isempty(playestado)
                    
                    
                fprintf("\n(vacio)El estado de play es(): %s y C es: %s\n",playestado,c)   
                app.mensajesclavemusica.Value = ">> Error, " + ...
                "para reproducir primero cargar los archivos " + ...
                "de audio.";
                
                elseif playestado == 1 
                    
                    
                fprintf("\n(uno)El estado de play es: %d y C es: %d\n",playestado,c)    
                    
                [y,Fs] = audioread(dircanciones(c));
                fprintf("\n(uno)El estado de play es: %s\n",dircanciones(c))    
                
                player = audioplayer(y,Fs);

                play(player);  
                
                app.Cancinactual.Value = canciones(c);
                app.mensajesclavemusica.Value = " ";
                fprintf("\n(uno)longitud de canciones: %d\n",length(canciones))    

                
                disp("inicio de mensajes")
                fprintf("\nCondicion1 tamaño canciones: %d\n",length(canciones))    
                
%                 if (length(canciones) > 1 && c < length(canciones))
%                 app.mensajesclavemusica.Value = "Siguiente canción" + ...
%                 "  >> " + canciones(c+1); 
%                 fprintf("\n(longituduno)Prueba mensajebox: %s\n",canciones(c+1))  
%                 end
%                 if c == length(canciones) 
%                 app.mensajesclavemusica.Value = "Siguiente canción" + ...
%                     "  >> " + canciones(c-1); 
%                 fprintf("\n(longituduno)Prueba mensajebox: %s\n",canciones(c+1))  
%                 end

                 disp("fin de mensajes")
                 disp("Inicio de duracion")
                duracion = (length(y(:,1))/(Fs));
                
                app.Slidermusica.Limits = [0 fix(duracion)]
                
                disp("Fin de duracion")
                disp("fin de mensajes")
              
                i = 1;
      
                disp("Pre while play")
                while i<=duracion
                    pause(1)
                    disp(i)
                    i=i+1;
                    app.Slidermusica.Value = i;

                    if estadopause2 == 1
                        break;
                    end
                end

                pause(9)
                
                %%%%%%%%%%%%%%%%%%%%%%%5
                
                 disp('Deberia iniciar la siguiente cancion')
                 SiguienteButtonPushed(app)

%                 SiguienteButtonPushed(app)
                
                %%%%%%%%%%%%%%%

                elseif playestado == 2
                fprintf("\n(pause)El estado de play es: %d y C es: %d\n",playestado,c)    
                    
                resume(player);
                playestado = 1;
                i = estadopause;
                estadopause2 = 0;
                disp("Pre while pause")
                while i<=duracion
                    pause(1)
                    disp(i)
                    i=i+1; 
                    app.Slidermusica.Value = i;
                    
                    if estadopause2 == 1
                        break;
                    end
                end

                pause(9)
                                
                %%%%%%%
                 disp('Deberia iniciar la siguiente cancion')
                 SiguienteButtonPushed(app)                    
                %%%%%%%%
                app.mensajesclavemusica.Value = [">> Reanudando:"
                ""
                ">> " + canciones(c)];
                app.Cancinactual.Value = canciones(c);
                end
    
            catch              
                
%                 app.mensajesclavemusica.Value = ">> Error " + ...
%                     "desconocido.";

            end
        end

        % Button pushed function: PausaButton
        function PausaButtonPushed(app, event)
            global player playestado estadopause estadopause2 i
            
            try
            estadopause = i-1;
            estadopause2 = 1;
            if isempty(playestado) 
             app.mensajesclavemusica.Value = ">> Error, " + ...
                "para pausar primero cargar los archivos " + ...
                "de audio.";   
            elseif playestado == 1
            pause(player)
            playestado = 2;
            app.mensajesclavemusica.Value =  ">> Reproductor de " + ...
                "música pausado. <<";
            end
            
            catch 
             app.mensajesclavemusica.Value =  ">> Error desconocido. <<";                          
            end

        end

        % Button pushed function: SiguienteButton
        function SiguienteButtonPushed(app, event)
            global canciones c dircanciones y Fs
             try
               c=c+1;
                                  
            if c>length(dircanciones) || c == 0                        
                c=1;       
            end
            fprintf("\n(next)El estado de C es: %d\n",c)    
          
             PlayButtonPushed(app)
                                 
             catch 
                disp('Error, carga la música')
             end

        end

        % Button pushed function: AnteriorButton
        function AnteriorButtonPushed(app, event)
            global canciones c player y Fs dircanciones
            try
               c=c-1;
            if c<=0               
                c=length(canciones);
            end
            disp(c)
                PlayButtonPushed(app)
            catch 
                disp('Error, carga la música')
            end

        end

        % Button pushed function: DetenerButton
        function DetenerButtonPushed(app, event)
            global player
            try            
            clear global; 
            app.mensajesclavemusica.Value = '';
            app.Cancinactual.Value = " ";
            stop(player);
            catch
            end

        end

        % Button pushed function: CargarvideoButton
        function CargarvideoButtonPushed(app, event)
            global dvideo videoname d c playerv y Fs estadocargav
        
            try 
                app.mensajesclave.Value = ['>> Cargando archivo,' ...
                    ' por favor esperar un momento.'];
                
                [videoname, d] = uigetfile({['*.wav;*.mp4;*.avi;' ...
                    '*.wmv;*.m4v;' ...
                    '*.m4a;*.mp4;*.mov']},'Selección de video'); 
                app.UIFigure.Visible = 'off';
                app.UIFigure.Visible = 'on';
                videoname = string(videoname);
                dvideo = d+videoname;
                c = 1;
               
                [y,Fs] = audioread(dvideo);           
                playerv = audioplayer(y,Fs);
               
                app.mensajesclave.Value = [">> Archivo listo " + ... 
                    "para extraer   >> " + '"' + videoname + '"' + " <<" 
                    '' 
                    '>> Siguiente: elegir nombre y extraer audio.'];
%                 '',
%                 '>> Siguiente: elegir nombre y extraer'];
                
                estadocargav = 1;

            catch
               
                app.mensajesclave.Value = ['>> Error al cargar ' ...
                    'archivo de video. ' ...
                    'Volver a intentar.'];

            end

        end

        % Button pushed function: ExtraeraudioButton
        function ExtraeraudioButtonPushed(app, event)
            global auextension y Fs extraccionaudio d videoname     
             global estadocargav estadoextracv
                
            if app.wavButton.Value == 1            
                    auextension = ".wav";
            elseif app.mp4Button.Value == 1
                    auextension = ".mp4";
            elseif app.m4aButton.Value == 1
                    auextension = ".m4a";
            elseif app.flacButton.Value == 1
                    auextension = ".flac";
            elseif app.oggButton.Value== 1
                    auextension = ".ogg";
            end
            
            try
                if app.ActivarNombre.Value == 1  
                    videoname =  string(app.NombreArchivoTexto.Value);       
                end                            
            
                extraccionaudio = d + videoname + auextension;                           
                audiowrite(extraccionaudio,y,Fs);            
                app.mensajesclave.Value = ['>> Dirección del audio:'
                ''
                extraccionaudio];                     
                estadoextracv = 1;        
            catch           
                if isempty(estadocargav)
                     app.mensajesclave.Value = ">> Error, " + ...
                         "antes de extraer el audio primero " + ...
                         "primer se debe cargar el " + ...
                         "archivo de video.";   
                else
                  app.mensajesclave.Value = ">> Error al guardar " + ...
                      "el audio.";
                 end
            end
%             app.mensajesclave.Value = ">> Error al guardar el audio," + ...
%                 " intentar de nuevo.";
        end

        % Button pushed function: PlayaudioButton
        function PlayaudioButtonPushed(app, event)
            global player extraccionaudio videoname auextension
            global estadocargav estadoextracv
            
            try
            [y,Fs] = audioread(extraccionaudio);           
            player = audioplayer(y,Fs);
            
            play(player);   
            app.mensajesclave.Value = ['>> Reproduciendo:' 
            ''
            '>> ' + videoname + auextension];

            catch
                 if isempty(estadocargav)
                     app.mensajesclave.Value = ">> Error, " + ...
                         "antes de reproducir el audio primero " + ...
                         "se debe cargar el " + ...
                         "archivo de video, y luego extraerlo.";                               
                 elseif isempty(estadoextracv)
                        app.mensajesclave.Value = ">> Error, " + ...
                         "antes de reproducir el audio " + ...
                         "es necesario extraer el audio."; 
                 else               
                     app.mensajesclave.Value = ">> Error " + ...
                         "desconocido al reproducir el audio.";
                end
            end
        end

        % Selection changed function: NombredelacancionButtonGroup
        function NombredelarchivoPanelSizeChanged(app, event)
            if app.ActivarNombre.Value == 1
                app.NombreArchivoTexto.Enable = 'on';
                app.NombreArchivoTexto.Value = '';

            else
                app.NombreArchivoTexto.Enable = 'off';
                app.NombreArchivoTexto.Value = ['Escribe el nombre ' ...
                    'del archivo'];         
            end
            
        end

        % Button pushed function: pruebasButton
        function pruebasButtonPushed(app, event)
         
              app.time.Value = string(datetime('now'));               

        end

        % Value changed function: time
        function timeValueChanged2(app, event)
%             value = app.time.Value;
            
             
            app.time.Value = datetime('now');               
            timeValueChanged2(app, event)

        end

        % Value changed function: Slidermusica
        function SlidermusicaValueChanged(app, event)
            value = app.Slidermusica.Value;
            
        end

        % Value changing function: Slidermusica
        function SlidermusicaValueChanging(app, event)
            global duracion 
% changingValue = event.Value;

            app.Slidermusica.Limits = [0 fix(duracion)]
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ReproductordemsicaTab = uitab(app.TabGroup);
            app.ReproductordemsicaTab.Title = 'Reproductor de música';
            app.CargarcarpetaButton = uibutton(app.ReproductordemsicaTab, 'push');
            app.CargarcarpetaButton.ButtonPushedFcn = createCallbackFcn(app, @CargarcarpetaButtonPushed, true);
            app.CargarcarpetaButton.Text = 'Cargar carpeta';
            app.CargararchivosButton = uibutton(app.ReproductordemsicaTab, 'push');
            app.CargararchivosButton.ButtonPushedFcn = createCallbackFcn(app, @CargararchivosButtonPushed, true);
            app.CargararchivosButton.Text = 'Cargar archivos';
            app.DetenerButton = uibutton(app.ReproductordemsicaTab, 'push');
            app.DetenerButton.ButtonPushedFcn = createCallbackFcn(app, @DetenerButtonPushed, true);
            app.DetenerButton.Text = 'Detener';
            app.Image = uiimage(app.ReproductordemsicaTab);
            app.Image.ImageSource = 'rabbit.png';
            app.ControlesdeaudioButtonGroup = uibuttongroup(app.ReproductordemsicaTab);
            app.ControlesdeaudioButtonGroup.Title = 'Controles de audio';
            app.PlayButton = uibutton(app.ControlesdeaudioButtonGroup, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Icon = 'rabbit.png';
            app.PlayButton.Text = 'Play';
            app.SiguienteButton = uibutton(app.ControlesdeaudioButtonGroup, 'push');
            app.SiguienteButton.ButtonPushedFcn = createCallbackFcn(app, @SiguienteButtonPushed, true);
            app.SiguienteButton.Text = 'Siguiente';
            app.PausaButton = uibutton(app.ControlesdeaudioButtonGroup, 'push');
            app.PausaButton.ButtonPushedFcn = createCallbackFcn(app, @PausaButtonPushed, true);
            app.PausaButton.Text = 'Pausa';
            app.AnteriorButton = uibutton(app.ControlesdeaudioButtonGroup, 'push');
            app.AnteriorButton.ButtonPushedFcn = createCallbackFcn(app, @AnteriorButtonPushed, true);
            app.AnteriorButton.Text = 'Anterior';
            app.CancinactualEditFieldLabel = uilabel(app.ReproductordemsicaTab);
            app.CancinactualEditFieldLabel.Text = 'Canción actual :';
            app.Cancinactual = uieditfield(app.ReproductordemsicaTab, 'text');
            app.Cancinactual.Editable = 'off';
            app.InformacindelprocesoLabel_2 = uilabel(app.ReproductordemsicaTab);
            app.InformacindelprocesoLabel_2.Text = 'Información del proceso:   ';
            app.mensajesclavemusica = uitextarea(app.ReproductordemsicaTab);
            app.mensajesclavemusica.Editable = 'off';
            app.pruebasButton = uibutton(app.ReproductordemsicaTab, 'push');
            app.pruebasButton.ButtonPushedFcn = createCallbackFcn(app, @pruebasButtonPushed, true);
            app.pruebasButton.Text = 'pruebas';
            app.ProgresoLabel = uilabel(app.ReproductordemsicaTab);
            app.ProgresoLabel.Enable = 'off';
            app.ProgresoLabel.Text = 'Progreso';
            app.Slidermusica = uislider(app.ReproductordemsicaTab);
            app.Slidermusica.ValueChangedFcn = createCallbackFcn(app, @SlidermusicaValueChanged, true);
            app.Slidermusica.ValueChangingFcn = createCallbackFcn(app, @SlidermusicaValueChanging, true);
            app.Slidermusica.Enable = 'off';
            app.KnobLabel = uilabel(app.ReproductordemsicaTab);
            app.KnobLabel.Text = 'Knob';
            app.ruedamusica = uiknob(app.ReproductordemsicaTab, 'discrete');
            app.ruedamusica.Items = {'x1', 'x1.50', 'x1.75', 'x2'};
            app.ruedamusica.Value = 'x1';
            app.ExtraccindeaudioTab = uitab(app.TabGroup);
            app.ExtraccindeaudioTab.Title = 'Extracción de audio';
            app.Image_2 = uiimage(app.ExtraccindeaudioTab);
            app.Image_2.ImageSource = 'rabbit.png';
            app.CargarvideoButton = uibutton(app.ExtraccindeaudioTab, 'push');
            app.CargarvideoButton.ButtonPushedFcn = createCallbackFcn(app, @CargarvideoButtonPushed, true);
            app.CargarvideoButton.Icon = 'rabbit.png';
            app.CargarvideoButton.Text = 'Cargar video';
            app.ExtraeraudioButton = uibutton(app.ExtraccindeaudioTab, 'push');
            app.ExtraeraudioButton.ButtonPushedFcn = createCallbackFcn(app, @ExtraeraudioButtonPushed, true);
            app.ExtraeraudioButton.Icon = 'rabbit.png';
            app.ExtraeraudioButton.Text = 'Extraer audio';
            app.ElegirformatodesalidadeaudioPanel = uipanel(app.ExtraccindeaudioTab);
            app.ElegirformatodesalidadeaudioPanel.Title = 'Elegir formato de salida de audio';
            app.FormatosdisponiblesButtonGroup = uibuttongroup(app.ElegirformatodesalidadeaudioPanel);
            app.FormatosdisponiblesButtonGroup.Title = 'Formatos disponibles';
            app.wavButton = uiradiobutton(app.FormatosdisponiblesButtonGroup);
            app.wavButton.Tag = 'wav';
            app.wavButton.Text = '.wav';
            app.wavButton.Value = true;
            app.oggButton = uiradiobutton(app.FormatosdisponiblesButtonGroup);
            app.oggButton.Tag = 'ogg';
            app.oggButton.Text = '.ogg';
            app.flacButton = uiradiobutton(app.FormatosdisponiblesButtonGroup);
            app.flacButton.Tag = 'flac';
            app.flacButton.Text = '.flac';
            app.mp4Button = uiradiobutton(app.FormatosdisponiblesButtonGroup);
            app.mp4Button.Tag = 'mp4';
            app.mp4Button.Text = '.mp4';
            app.m4aButton = uiradiobutton(app.FormatosdisponiblesButtonGroup);
            app.m4aButton.Tag = 'm4a';
            app.m4aButton.Text = '.m4a';
            app.PlayaudioButton = uibutton(app.ExtraccindeaudioTab, 'push');
            app.PlayaudioButton.ButtonPushedFcn = createCallbackFcn(app, @PlayaudioButtonPushed, true);
            app.PlayaudioButton.Icon = 'rabbit.png';
            app.PlayaudioButton.Text = 'Play audio';
            app.NombredelacancionButtonGroup = uibuttongroup(app.ExtraccindeaudioTab);
            app.NombredelacancionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @NombredelarchivoPanelSizeChanged, true);
            app.NombredelacancionButtonGroup.Title = 'Nombre de la cancion';
            app.DefectoButton = uiradiobutton(app.NombredelacancionButtonGroup);
            app.DefectoButton.Text = 'Defecto';
            app.DefectoButton.Value = true;
            app.ActivarNombre = uiradiobutton(app.NombredelacancionButtonGroup);
            app.ActivarNombre.Text = 'Personalizado:';
            app.NombreArchivoTexto = uieditfield(app.NombredelacancionButtonGroup, 'text');
            app.NombreArchivoTexto.Enable = 'off';
            app.NombreArchivoTexto.Value = 'Escribe el nombre de la canción';
            app.InformacindelprocesoLabel = uilabel(app.ExtraccindeaudioTab);
            app.InformacindelprocesoLabel.Text = 'Información del proceso:   ';
            app.mensajesclave = uitextarea(app.ExtraccindeaudioTab);
            app.mensajesclave.Editable = 'off';
            app.Instrucciones1Cargararrchivodevideo2ElegirformatodelaudioLabel = uilabel(app.ExtraccindeaudioTab);
            app.Instrucciones1Cargararrchivodevideo2ElegirformatodelaudioLabel.Text = {'Instrucciones:'; ''; '      1) Cargar archivo de video'; ''; '      2) Elegir formato del audio'; ''; '      3) El nombre por defecto es '; '      el nombre del archivo original'; ''; '      4) Extraer audio'; ''; '      5) Se puede reproducir el audio '; '      una vez garcado el video, y usar'; '      los controles del reproductor'};
            app.EditFieldLabel = uilabel(app.ExtraccindeaudioTab);
            app.EditFieldLabel.Text = 'Edit Field';
            app.time = uieditfield(app.ExtraccindeaudioTab, 'text');
            app.time.ValueChangedFcn = createCallbackFcn(app, @timeValueChanged2, true);
            app.time.Editable = 'off';
            app.ButtonGroup = uibuttongroup(app.ExtraccindeaudioTab);
            app.ButtonGroup.Title = 'Button Group';
            app.SegundosButton = uiradiobutton(app.ButtonGroup);
            app.SegundosButton.Text = 'Segundos';
            app.SegundosButton.Value = true;
            app.MinutosButton = uiradiobutton(app.ButtonGroup);
            app.MinutosButton.Text = 'Minutos';
            app.Button3 = uiradiobutton(app.ButtonGroup);
            app.Button3.Text = 'Button3';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ReproductorDeMusica

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end